classdef ASR_6000_userinterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        VASliderLabel             matlab.ui.control.Label
        VASlider                  matlab.ui.control.Slider
        Spinner                   matlab.ui.control.Spinner
        Angle12Label              matlab.ui.control.Label
        Angle13Label              matlab.ui.control.Label
        Spinner_2                 matlab.ui.control.Spinner
        OutputSwitchLabel         matlab.ui.control.Label
        OutputSwitch              matlab.ui.control.Switch
        CommunicationSwitchLabel  matlab.ui.control.Label
        CommunicationSwitch       matlab.ui.control.Switch
        VBSliderLabel             matlab.ui.control.Label
        VBSlider                  matlab.ui.control.Slider
        VCSliderLabel             matlab.ui.control.Label
        VCSlider                  matlab.ui.control.Slider
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        TextAreaLabel             matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        S  % Description
    end
    
    methods (Access = private)
        
        function dibujar(app)
            Va = app.VASlider.Value;
            Vb = app.VBSlider.Value;
            Vc = app.VCSlider.Value;
            angleb = app.Spinner.Value;
            anblec = app.Spinner_2.Value;
            hold( app.UIAxes, 'off' )
            quiver(app.UIAxes, 0, 0, Va*cosd(0), Va*sind(0));
            hold( app.UIAxes, 'on' )
            quiver(app.UIAxes, 0, 0, Vb*cosd(-angleb), Vb*sind(-angleb));
            quiver(app.UIAxes, 0, 0, Vc*cosd(-anblec), Vc*sind(-anblec));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CommunicationSwitch
        function CommunicationSwitchValueChanged(app, event)
            value = app.CommunicationSwitch.Value;
            if (strcmp(value,'On'))
                try
                    app.S = serialport("COM3",115200);
                    app.TextArea.Value = "conectado";
                    writeline(app.S,':INST:EDIT EACH');
                catch ME
                    app.TextArea.Value = "no se pudo conectar";
                end
            else
                try
                    delete(app.S);
                catch ME
                end
                app.TextArea.Value = "desconectado";
            end
        end

        % Value changed function: VASlider
        function VASliderValueChanged(app, event)
            value = app.VASlider.Value;
            app.dibujar();
            writeline(app.S,':INST:SEL L1');
            writeline(app.S, append(':VOLT ', num2str(value)));
            writeline(app.S,':VOLT?');
            text = readline(app.S);
            app.TextArea.Value = text;
            
            
        end

        % Value changed function: VBSlider
        function VBSliderValueChanged(app, event)
            value = app.VBSlider.Value;
            app.dibujar();
            writeline(app.S,':INST:SEL L2');
            writeline(app.S, append(':VOLT ', num2str(value)));
            writeline(app.S,':VOLT?');
            text = readline(app.S);
            app.TextArea.Value = text;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            app.dibujar();
            writeline(app.S, append(':PHAS:PHAS L12,', num2str(value)));
            writeline(app.S,':PHAS:PHAS? L12');
            text = readline(app.S);
            app.TextArea.Value = text;
           
        end

        % Value changed function: VCSlider
        function VCSliderValueChanged(app, event)
            value = app.VCSlider.Value;
            app.dibujar();
            writeline(app.S,':INST:SEL L3');
            writeline(app.S, append(':VOLT ', num2str(value)));
            writeline(app.S,':VOLT?');
            text = readline(app.S);
            app.TextArea.Value = text;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            app.dibujar();
            writeline(app.S, append(':PHAS:PHAS L13,', num2str(value)));
            writeline(app.S,':PHAS:PHAS? L13');
            text = readline(app.S);
            app.TextArea.Value = text;
        end

        % Value changed function: OutputSwitch
        function OutputSwitchValueChanged(app, event)
            value = app.OutputSwitch.Value;
            if (strcmp(value,'On'))
               writeline(app.S,':OUTP 1');
            else
                writeline(app.S,':OUTP 0');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VASliderLabel = uilabel(app.LeftPanel);
            app.VASliderLabel.Text = 'VA';
            app.VASlider = uislider(app.LeftPanel);
            app.VASlider.Limits = [0 60];
            app.VASlider.MajorTicks = [0 20 40 60];
            app.VASlider.ValueChangedFcn = createCallbackFcn(app, @VASliderValueChanged, true);
            app.VASlider.MinorTicks = [5 10 15 25 30 35 45 50 55];
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 120;
            app.Angle12Label = uilabel(app.LeftPanel);
            app.Angle12Label.Text = 'Angle1-2';
            app.Angle13Label = uilabel(app.LeftPanel);
            app.Angle13Label.Text = 'Angle1-3';
            app.Spinner_2 = uispinner(app.LeftPanel);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 120;
            app.OutputSwitchLabel = uilabel(app.LeftPanel);
            app.OutputSwitchLabel.Text = 'Output';
            app.OutputSwitch = uiswitch(app.LeftPanel, 'slider');
            app.OutputSwitch.ValueChangedFcn = createCallbackFcn(app, @OutputSwitchValueChanged, true);
            app.CommunicationSwitchLabel = uilabel(app.LeftPanel);
            app.CommunicationSwitchLabel.Text = 'Communication';
            app.CommunicationSwitch = uiswitch(app.LeftPanel, 'slider');
            app.CommunicationSwitch.ValueChangedFcn = createCallbackFcn(app, @CommunicationSwitchValueChanged, true);
            app.VBSliderLabel = uilabel(app.LeftPanel);
            app.VBSliderLabel.Text = 'VB';
            app.VBSlider = uislider(app.LeftPanel);
            app.VBSlider.Limits = [0 60];
            app.VBSlider.MajorTicks = [0 20 40 60];
            app.VBSlider.ValueChangedFcn = createCallbackFcn(app, @VBSliderValueChanged, true);
            app.VBSlider.MinorTicks = [5 10 15 25 30 35 45 50 55];
            app.VCSliderLabel = uilabel(app.LeftPanel);
            app.VCSliderLabel.Text = 'VC';
            app.VCSlider = uislider(app.LeftPanel);
            app.VCSlider.Limits = [0 60];
            app.VCSlider.MajorTicks = [0 20 40 60];
            app.VCSlider.ValueChangedFcn = createCallbackFcn(app, @VCSliderValueChanged, true);
            app.VCSlider.MinorTicks = [5 10 15 25 30 35 45 50 55];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-60 60];
            app.UIAxes.YLim = [-60 60];
            app.UIAxes.XTick = [-60 0 60];
            app.UIAxes.XTickLabel = {'-60'; '0'; '60'};
            app.UIAxes.YTick = [-60 0 60];
            app.UIAxes.YTickLabel = {'-60'; '0'; '60'};
            app.TextAreaLabel = uilabel(app.RightPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASR_6000_userinterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end